classdef covid_19 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COVID19DataRepresentationUIFigure  matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        CountryLabel               matlab.ui.control.Label
        CountryListBox             matlab.ui.control.ListBox
        StateorRegionListBoxLabel  matlab.ui.control.Label
        StateorRegionListBox       matlab.ui.control.ListBox
        DatatoPlotButtonGroup      matlab.ui.container.ButtonGroup
        CasesButton                matlab.ui.control.RadioButton
        DeathsButton               matlab.ui.control.RadioButton
        BothButton                 matlab.ui.control.RadioButton
        OptionsButtonGroup         matlab.ui.container.ButtonGroup
        CumulativeButton           matlab.ui.control.RadioButton
        DailyButton                matlab.ui.control.RadioButton
        AveragedofDaysSliderLabel  matlab.ui.control.Label
        AveragedofDaysSlider       matlab.ui.control.Slider
    end

    
    properties (Access = private)
        gClass % instance of Data Class contains all data
        date
        %for radio buttons (Data to Plot)
        Cases
        Deaths
        
        Cindex %Country index in list
        Sindex %State index in list
        
        Average_value %For averaging window
        
        %For options radio buttons
        Cumulative
        Daily
    end
    
    
    methods (Access = private)
        
        function PlotData(app)
            cla(app.UIAxes,'reset')
            grid (app.UIAxes,"on");
            xlabel(app.UIAxes,"Month/Day","FontSize",11);
            ylabel(app.UIAxes,"Numbers","FontSize",11);
            if app.Cases
                app.gClass=app.gClass.Calc_Cases(app.Cindex,app.Sindex);
                yData=app.gClass.Cases;
                yData=app.Averaging_Data(yData);
                ylim(app.UIAxes,[0,1.15*max(yData)]);
                if app.CumulativeButton.Value
                    bar(app.UIAxes,app.date,yData);
                    xticks(app.UIAxes,linspace(app.date(1),app.date(end),14));
                    datetick(app.UIAxes,'x','mm/dd','keepticks','keeplimits');
                elseif app.DailyButton.Value
                    app.gClass=app.gClass.Calc_Daily_Cases();
                    yData=app.gClass.Daily_Cases;
                    ylim(app.UIAxes,[0,1.15*max(yData)]);
                    yData=app.Averaging_Data(yData);
                    bar(app.UIAxes,app.date,yData);
                    xticks(app.UIAxes,linspace(app.date(1),app.date(end),14));
                    datetick(app.UIAxes,'x','mm/dd','keepticks','keeplimits');
                end
                app.PrintTitle();
            end
            if app.Deaths
                if app.Cases
                    hold(app.UIAxes,"on");
                    yyaxis (app.UIAxes,"right");
                    legend(app.UIAxes,"Cases","Deaths");
                end
                app.gClass=app.gClass.Calc_Deaths(app.Cindex,app.Sindex);
                yData=app.gClass.Deaths;
                ylim(app.UIAxes,[0,1.15*max(yData)]);
                yData=app.Averaging_Data(yData);
                if app.CumulativeButton.Value
                    plot(app.UIAxes,app.date,yData,"r");
                    xticks(app.UIAxes,linspace(app.date(1),app.date(end),14));
                    datetick(app.UIAxes,'x','mm/dd','keepticks','keeplimits');
                elseif app.DailyButton.Value
                    app.gClass=app.gClass.Calc_Daily_Deaths();
                    yData=app.gClass.Daily_Deaths;
                    ylim(app.UIAxes,[0,1.15*max(yData)]);
                    yData=app.Averaging_Data(yData);
                    plot(app.UIAxes,app.date,yData,"r");
                    xticks(app.UIAxes,linspace(app.date(1),app.date(end),14));
                    datetick(app.UIAxes,'x','mm/dd','keepticks','keeplimits');
                end
                app.PrintTitle();
            end
        end
        
        
        function PrintTitle(app)
            name=app.gClass.Countries_Names{app.Cindex};
            if app.Cindex==1
                name ="The Whole world";
            end
            if app.CasesButton.Value
                if app.Cumulative
                    if app.Sindex==1 && app.Average_value==1
                        title(app.UIAxes,sprintf("Cumulative number of Cases in %s",name),"FontSize",16);
                    elseif app.Sindex==1 && app.Average_value>1
                        title(app.UIAxes,sprintf("Cumulative number of Cases in %s (%d-day mean)",name,app.Average_value),"FontSize",16);
                    elseif app.Sindex>1 && app.Average_value==1
                        title(app.UIAxes,sprintf("Cumulative number of Cases in %s",app.gClass.States_Names{app.Cindex,app.Sindex}),"FontSize",16);
                    elseif app.Sindex>1 && app.Average_value>1
                        title(app.UIAxes,sprintf("Cumulative number of Cases in %s (%d-day mean)",app.gClass.States_Names{app.Cindex,app.Sindex},app.Average_value),"FontSize",16);
                    end
                else
                    if app.Sindex==1 && app.Average_value==1
                        title(app.UIAxes,sprintf("Daily number of Cases in %s",name,"FontSize",16));
                    elseif app.Sindex==1 && app.Average_value>1
                        title(app.UIAxes,sprintf("Daily number of Cases in %s (%d-day mean)",name,app.Average_value),"FontSize",16);
                    elseif app.Sindex>1 && app.Average_value==1
                        title(app.UIAxes,sprintf("Daily number of Cases in %s",app.gClass.States_Names{app.Cindex,app.Sindex}),"FontSize",16);
                    elseif app.Sindex>1 && app.Average_value>1
                        title(app.UIAxes,sprintf("Daily number of Cases in %s (%d-day mean)",app.gClass.States_Names{app.Cindex,app.Sindex},app.Average_value),"FontSize",16);
                    end
                end
            elseif app.DeathsButton.Value
                if app.Cumulative
                    if app.Sindex==1 && app.Average_value==1
                        title(app.UIAxes,sprintf("Cumulative number of Deaths in %s",name,"FontSize",16));
                    elseif app.Sindex==1 && app.Average_value>1
                        title(app.UIAxes,sprintf("Cumulative number of Deaths in %s (%d-day mean)",name,app.Average_value),"FontSize",16);
                    elseif app.Sindex>1 && app.Average_value==1
                        title(app.UIAxes,sprintf("Cumulative number of Deaths in %s",app.gClass.States_Names{app.Cindex,app.Sindex}),"FontSize",16);
                    elseif app.Sindex>1 && app.Average_value>1
                        title(app.UIAxes,sprintf("Cumulative number of Deaths in %s (%d-day mean)",app.gClass.States_Names{app.Cindex,app.Sindex},app.Average_value),"FontSize",16);
                    end
                else
                    if app.Sindex==1 && app.Average_value==1
                        title(app.UIAxes,sprintf("Daily number of Deaths in %s",name,"FontSize",16));
                    elseif app.Sindex==1 && app.Average_value>1
                        title(app.UIAxes,sprintf("Daily number of Deaths in %s (%d-day mean)",name,app.Average_value),"FontSize",16);
                    elseif app.Sindex>1 && app.Average_value==1
                        title(app.UIAxes,sprintf("Daily number of Deaths in %s",app.gClass.States_Names{app.Cindex,app.Sindex}),"FontSize",16);
                    elseif app.Sindex>1 && app.Average_value>1
                        title(app.UIAxes,sprintf("Daily number of Deaths in %s (%d-day mean)",app.gClass.States_Names{app.Cindex,app.Sindex},app.Average_value),"FontSize",16);
                    end
                end
            else
                if app.Cumulative
                    if app.Sindex==1 && app.Average_value==1
                        title(app.UIAxes,sprintf("Cumulative number of Cases and Deaths in %s",name),"FontSize",16);
                    elseif app.Sindex==1 && app.Average_value>1
                        title(app.UIAxes,sprintf("Cumulative number of Cases and Deaths in %s (%d-day mean)",name,app.Average_value),"FontSize",16);
                    elseif app.Sindex>1 && app.Average_value==1
                        title(app.UIAxes,sprintf("Cumulative number of Cases and Deaths in %s",app.gClass.States_Names{app.Cindex,app.Sindex}),"FontSize",16);
                    elseif app.Sindex>1 && app.Average_value>1
                        title(app.UIAxes,sprintf("Cumulative number of Cases and Deaths in %s (%d-day mean)",app.gClass.States_Names{app.Cindex,app.Sindex},app.Average_value),"FontSize",16);
                    end
                else
                    if app.Sindex==1 && app.Average_value==1
                        title(app.UIAxes,sprintf("Daily number of Cases and Deaths in %s",name),"FontSize",16);
                    elseif app.Sindex==1 && app.Average_value>1
                        title(app.UIAxes,sprintf("Daily number of Cases and Deaths in %s (%d-day mean)",name,app.Average_value),"FontSize",16);
                    elseif app.Sindex>1 && app.Average_value==1
                        title(app.UIAxes,sprintf("Daily number of Cases and Deaths in %s",app.gClass.States_Names{app.Cindex,app.Sindex}),"FontSize",16);
                    elseif app.Sindex>1 && app.Average_value>1
                        title(app.UIAxes,sprintf("Daily number of Cases and Deaths in %s (%d-day mean)",app.gClass.States_Names{app.Cindex,app.Sindex},app.Average_value),"FontSize",16);
                    end
                end
            end
        end
        
        function  averaged_data =Averaging_Data(app,input)
            averaged_data=movmean(input,app.Average_value);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.gClass=Data;
            app.date=datenum(app.gClass.Date);
            app.CountryListBox.Items=app.gClass.Countries_Names;
            app.CountryListBox.Value=app.CountryListBox.Items(1);
            app.StateorRegionListBox.Items="All";
            app.StateorRegionListBox.Value="All";
            app.Cases=1;
            app.Cindex=1;
            app.Sindex=1;
            app.Average_value=1;
            app.Cumulative=1;
            app.PlotData();
        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
            app.Cases=app.BothButton.Value||app.CasesButton.Value;
            app.Deaths=app.BothButton.Value||app.DeathsButton.Value;
            app.PlotData();
            
        end

        % Selection changed function: OptionsButtonGroup
        function OptionsButtonGroupSelectionChanged(app, event)
            app.Cumulative=app.CumulativeButton.Value;
            app.Daily=app.DailyButton.Value;
            app.PlotData();
        end

        % Value changed function: AveragedofDaysSlider
        function AveragedofDaysSliderValueChanged(app, event)
            app.Average_value = round(app.AveragedofDaysSlider.Value);
            app.PlotData();
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            app.CountryListBox.Items=app.gClass.Countries_Names;
            [~,app.Cindex]=ismember(app.CountryListBox.Value,app.CountryListBox.Items);
            States=app.gClass.States_Names(app.Cindex,1:end);
            States=States(~cellfun("isempty",States)); %To remove [] from states cells
            app.StateorRegionListBox.Items=States;
            app.StateorRegionListBox.Value="All";
            app.Sindex=1;
            app.PlotData();
        end

        % Value changed function: StateorRegionListBox
        function StateorRegionListBoxValueChanged(app, event)
            [~,app.Sindex]=ismember(app.StateorRegionListBox.Value,app.StateorRegionListBox.Items);
            app.PlotData();
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COVID19DataRepresentationUIFigure = uifigure('Visible', 'off');
            app.COVID19DataRepresentationUIFigure.Name = 'COVID-19 Data Representation';
            app.UIAxes = uiaxes(app.COVID19DataRepresentationUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.CountryLabel = uilabel(app.COVID19DataRepresentationUIFigure);
            app.CountryLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.COVID19DataRepresentationUIFigure);
            app.CountryListBox.Items = {};
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.CountryListBox.Value = {};
            app.StateorRegionListBoxLabel = uilabel(app.COVID19DataRepresentationUIFigure);
            app.StateorRegionListBoxLabel.Text = {'State or'; 'Region:'};
            app.StateorRegionListBox = uilistbox(app.COVID19DataRepresentationUIFigure);
            app.StateorRegionListBox.Items = {};
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
            app.StateorRegionListBox.Value = {};
            app.DatatoPlotButtonGroup = uibuttongroup(app.COVID19DataRepresentationUIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionsButtonGroup = uibuttongroup(app.COVID19DataRepresentationUIFigure);
            app.OptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionsButtonGroupSelectionChanged, true);
            app.OptionsButtonGroup.Title = 'Options';
            app.CumulativeButton = uiradiobutton(app.OptionsButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionsButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.AveragedofDaysSliderLabel = uilabel(app.COVID19DataRepresentationUIFigure);
            app.AveragedofDaysSliderLabel.Text = {'Averaged'; 'of Days'};
            app.AveragedofDaysSlider = uislider(app.COVID19DataRepresentationUIFigure);
            app.AveragedofDaysSlider.Limits = [1 15];
            app.AveragedofDaysSlider.ValueChangedFcn = createCallbackFcn(app, @AveragedofDaysSliderValueChanged, true);
            app.AveragedofDaysSlider.Value = 1;
            % Show the figure after all components are created
            app.COVID19DataRepresentationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = covid_19

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COVID19DataRepresentationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COVID19DataRepresentationUIFigure)
        end
    end
end